classdef simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NumChannelsEditField            matlab.ui.control.NumericEditField
        NumChannelsEditFieldLabel       matlab.ui.control.Label
        PatternLengthEditField          matlab.ui.control.NumericEditField
        PatternLengthEditFieldLabel     matlab.ui.control.Label
        VanAttaelementsEditField        matlab.ui.control.NumericEditField
        VanAttaelementsEditFieldLabel   matlab.ui.control.Label
        symbolfreqEditField             matlab.ui.control.NumericEditField
        symbolfreqEditFieldLabel        matlab.ui.control.Label
        FbStepEditField                 matlab.ui.control.NumericEditField
        FbStepEditFieldLabel            matlab.ui.control.Label
        InterchannelSpacingEditField    matlab.ui.control.NumericEditField
        InterchannelSpacingEditFieldLabel  matlab.ui.control.Label
        amplitudeEditField              matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        ofsymbolsEditField              matlab.ui.control.NumericEditField
        ofsymbolsEditFieldLabel         matlab.ui.control.Label
        FbBaseEditField                 matlab.ui.control.NumericEditField
        FbBaseEditFieldLabel            matlab.ui.control.Label
        simstepspersymbolEditField      matlab.ui.control.NumericEditField
        simstepspersymbolEditFieldLabel  matlab.ui.control.Label
        FsGranularityEditField          matlab.ui.control.NumericEditField
        FsGranularityEditFieldLabel     matlab.ui.control.Label
        SimulationParametersLabel       matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TagLocationsTab                 matlab.ui.container.Tab
        MaxXEditFieldLabel              matlab.ui.control.Label
        MaxYEditFieldLabel              matlab.ui.control.Label
        MaxZEditFieldLabel              matlab.ui.control.Label
        deleteidxEditFieldLabel         matlab.ui.control.Label
        NumTagsEditFieldLabel           matlab.ui.control.Label
        TypeDropDownLabel               matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditFieldLabel                 matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        MaxXEditField                   matlab.ui.control.NumericEditField
        MaxYEditField                   matlab.ui.control.NumericEditField
        MaxZEditField                   matlab.ui.control.NumericEditField
        DeleteButton                    matlab.ui.control.Button
        deleteidxEditField              matlab.ui.control.NumericEditField
        CreateButton                    matlab.ui.control.Button
        ManualCreationLabel             matlab.ui.control.Label
        RandomCreationLabel             matlab.ui.control.Label
        RandomFChannelCheckBox          matlab.ui.control.CheckBox
        NumTagsEditField                matlab.ui.control.NumericEditField
        AddButton                       matlab.ui.control.Button
        TypeDropDown                    matlab.ui.control.DropDown
        UITable_3                       matlab.ui.control.Table
        ZEditField                      matlab.ui.control.NumericEditField
        YEditField                      matlab.ui.control.NumericEditField
        XEditField                      matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        SimulationTab                   matlab.ui.container.Tab
        SNRdbEditField                  matlab.ui.control.NumericEditField
        SNRdbEditFieldLabel             matlab.ui.control.Label
        TagidxEditField                 matlab.ui.control.NumericEditField
        TagidxEditFieldLabel            matlab.ui.control.Label
        NumSimStepsEditField            matlab.ui.control.NumericEditField
        NumSimStepsEditFieldLabel       matlab.ui.control.Label
        DemodulateButton                matlab.ui.control.Button
        UITable_4                       matlab.ui.control.Table
        AlignButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DataSigButton                   matlab.ui.control.Button
        SimulationLabel                 matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        FrequencyHoppingTab             matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        curr_point = [0, 0, 0]; % Current point being drawn on-screen
        curr_type = "FSK_LO";
        all_points = [];
        all_types = strings(0);
        modded_steps = [];
        sim = [];
        aligned = struct("signal", [], "time", [], "carrier", []);
    end

    properties (Access = public, Dependent)
        simconsts
    end
    
    methods
        
        function set.curr_point(app, coords)
            arguments
                app
                coords (1,3)
            end

            disp(coords);
            app.curr_point = coords;
        end

        function res = get.simconsts(app)
            import bherber.thesis.SimulationConstants
            res = SimulationConstants( ...
                app.CarrierFrequencyEditField.Value, ... "Fc"
                app.FbBaseEditField.Value, ... "Fb_base"
                app.FbStepEditField.Value, ... "Fb_step"
                app.InterchannelSpacingEditField.Value, ... "Fb_channel_spacing"
                app.NumChannelsEditField.Value, ... "num_channels"
                app.FsGranularityEditField.Value, ... "fs_granularity"
                app.ofsymbolsEditField.Value, ... "num_symbs"
                app.symbolfreqEditField.Value, ... "symb_freq"
                app.simstepspersymbolEditField.Value, ... "sim_sym_ratio"
                app.amplitudeEditField.Value, ... "amplitude"
                app.VanAttaelementsEditField.Value,  ... "num_elements"
                app.PatternLengthEditField.Value ... "pattern_len"
                );
        end

    end
    
    methods (Access = private)
        
        function plot_curr(app)
            scatter(app.UIAxes, app.curr_point(1), app.curr_point(2), "*r")
        end
        
        function make_table(app)
            import bherber.thesis.TagType
            distances = sqrt(app.all_points(:, 1) .^2 + app.all_points(:, 2) .^2 + ...
                app.all_points(:, 3) .^2);
            app.UITable_3.Data = [(1:length(app.all_types)).', app.all_points(:, 1), ...
                app.all_points(:, 2), app.all_points(:, 3), distances(:), app.all_types(:)];
            app.UITable_3.ForegroundColor
            
            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            scatter(app.UIAxes, 0, 0, "hk", MarkerFaceColor="black")
            for idx = 1:length(app.all_types)
                if app.all_types(idx) == "FREQ_HOP"
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="v", MarkerEdgeColor="#EB4034", MarkerFaceColor="#EB4034")
                elseif app.all_types(idx) == "FSK_LO"
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="v", MarkerEdgeColor="#1B5299", MarkerFaceColor="#1B5299")
                elseif app.all_types(idx) == "FSK_HI"
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="^", MarkerEdgeColor="#48BEFF", MarkerFaceColor="#48BEFF")
                else
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="v", MarkerEdgeColor="#DEB841", MarkerFaceColor="#DEB841")
                end
            end

            if ~isempty(app.all_points) && (max(app.all_points(:, 1:2), [], "all") ~= 0)
                lim = 1.2 * abs(max(abs(app.all_points(:, 1:2)), [], "all"));
            else
                lim = 2;
            end

            app.UIAxes.XLim = [-lim, lim];
            app.UIAxes.YLim = [-lim, lim];

            hold(app.UIAxes, "off");
        end
        
        function make_fh_heatmap(app)
            data = zeros(length(app.sim.tags), app.PatternLengthEditField.Value);
            for idx = 1:length(app.sim.tags)
                data(idx, :) = app.sim.tags(idx).fh_pattern(:);
            end
            xlabels = strings(1, app.PatternLengthEditField.Value);
            for idx = 1:app.PatternLengthEditField.Value
                xlabels(idx) = "h" + string(idx);
            end
            ylabels = strings(1, length(app.sim.tags));
            for idx = 1:length(app.sim.tags)
                ylabels(idx) = "tag" + string(idx);
            end
            cb_labels = strings(1, length(app.sim.tags));
            for idx = 1:app.NumChannelsEditField.Value
                cb_labels(idx) = "channel" + string(idx);
            end
            imagesc(app.UIAxes3, data, [1, app.NumChannelsEditField.Value]);
            colorbar(app.UIAxes3, Ticks=1:app.NumChannelsEditField.Value, TickLabels=cb_labels);
            title(app.UIAxes3, "Hopsets for All Tags in Network");
            xlabel(app.UIAxes3, "Hopsets");
            ylabel(app.UIAxes3, "Tag Number");
            xticks(app.UIAxes3, 1:app.PatternLengthEditField.Value);
            xticklabels(app.UIAxes3, xlabels);
            xlim(app.UIAxes3, [0.5, 0.5 + app.PatternLengthEditField.Value]);
            ylim(app.UIAxes3, [0.5, 0.5 + length(app.sim.tags)]);
            yticks(app.UIAxes3, 1:length(app.sim.tags));
            yticklabels(app.UIAxes3, ylabels);
            colormap(app.UIAxes3, hsv(app.NumChannelsEditField.Value));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            fhv = app.UIFigure.HandleVisibility;
            app.UIFigure.HandleVisibility = "callback";
            set(0, "CurrentFigure", app.UIFigure)
            
            [x, y, ~] = ginput(1);
    
            app.UIFigure.HandleVisibility = fhv;
            
            app.curr_point = [x, y, 0];
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.ZEditField.Value = 0;

            app.plot_curr();
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.curr_point(1) = app.XEditField.Value;
            app.plot_curr();
            % UPDATE VIEW
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.curr_point(2) = app.YEditField.Value;
            app.plot_curr();
            % UPDATE VIEW
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.curr_point(3) = app.ZEditField.Value;
            app.plot_curr();
            % UPDATE VIEW
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.curr_type = app.TypeDropDown.Value;
            app.plot_curr();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.all_points = [app.all_points; app.curr_point];
            app.all_types = [app.all_types; string(app.curr_type)];
            app.make_table()
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            idx = app.deleteidxEditField.Value;
            if size(app.all_points, 1) == 1
                app.all_points = [];
                app.all_types = [];
                app.UITable_3.Data = [];
            else
                app.all_points(idx, :) = [];
                app.all_types(idx) = [];
                app.make_table();
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            types = strings(app.NumTagsEditField.Value, 1);
            if app.RandomFChannelCheckBox.Value && ...
                    ~((app.TypeDropDown.Value == "OOK") || (app.TypeDropDown.Value == "FREQ_HOP"))
                for idx = 1:length(types)
                    if logical(randi([0, 1], 1))
                        types(idx) = "FSK_LO";
                    else
                        types(idx) = "FSK_HI";
                    end
                end
            else
                for idx = 1:length(types)
                    types(idx) = app.TypeDropDown.Value;
                end
            end
            app.all_types = types;

            app.all_points = zeros(app.NumTagsEditField.Value, 3);
            x = (2 * app.MaxXEditField.Value) .* rand(app.NumTagsEditField.Value, 1) - app.MaxXEditField.Value;
            y = (2 * app.MaxYEditField.Value) .* rand(app.NumTagsEditField.Value, 1) - app.MaxYEditField.Value;
            z = (2 * app.MaxZEditField.Value) .* rand(app.NumTagsEditField.Value, 1) - app.MaxZEditField.Value;
            app.all_points(:, 1) = x(:);
            app.all_points(:, 2) = y(:);
            app.all_points(:, 3) = z(:);

            app.make_table();
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            import bherber.thesis.Simulator bherber.thesis.TagType bherber.thesis.awgn_channel
            types = [TagType.FSK_LO];
            types = repelem(types, length(app.all_types));
            for idx = 1:length(types)
                switch app.all_types(idx)
                    case "OOK"
                        types(idx) = TagType.OOK;
                    case "FSK_LO"
                        types(idx) = TagType.FSK_LO;
                    case "FREQ_HOP"
                        types(idx) = TagType.FREQ_HOP;
                    otherwise
                        types(idx) = TagType.FSK_HI;
                end
            end

            channel = @(signal) awgn_channel(signal, snr_db=app.SNRdbEditField.Value);
            
            if isempty(app.sim)
                app.sim = Simulator(app.all_points.', types, channel, app.simconsts);
            end
            
            app.modded_steps = zeros(length(app.sim.tags), (app.NumSimStepsEditField.Value * app.simconsts.simstep_sz));
            for idx = 1:app.NumSimStepsEditField.Value
                start = (idx - 1) * app.simconsts.simstep_sz + 1;
                stop = idx * app.simconsts.simstep_sz;
                app.modded_steps(:, start:stop) = app.sim.step();
            end


            for idx = 1:size(app.modded_steps, 1)
                app.modded_steps(idx, :) = channel(app.modded_steps(idx, :));
            end

            app.modded_steps = awgn_channel(sum(app.modded_steps, 1), snr_db=20); % FIXME: SUS

            start = (app.sim.curr_step - app.NumSimStepsEditField.Value) * app.simconsts.simstep_sz + 1;
            stop = (start + (app.NumSimStepsEditField.Value - 1)) * app.simconsts.simstep_sz;
            time = app.sim.time(start:stop);

            plot(app.UIAxes2, time, app.modded_steps(:));

            lim = 1.2 * abs(real(max(abs(app.modded_steps(:)))));

            app.UIAxes2.YLim = [-lim, lim];

            if ~isempty(app.sim.tags) && (app.sim.tags(1).mode == TagType.FREQ_HOP)
                app.make_fh_heatmap()
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.modded_steps = [];
            app.sim = [];
            app.UITable_4.Data = [];
            cla(app.UIAxes2);
        end

        % Button pushed function: DataSigButton
        function DataSigButtonPushed(app, event)
            start = (app.sim.curr_step - app.NumSimStepsEditField.Value) * app.simconsts.simstep_sz + 1;
            stop = (start + (app.NumSimStepsEditField.Value - 1)) * app.simconsts.simstep_sz;
            time = app.sim.time(start:stop);
            data = app.sim.tags(app.TagidxEditField.Value).data(start:stop) * abs(max(app.modded_steps(:)));
            hold(app.UIAxes2, "on");
            plot(app.UIAxes2, time, data);
            hold(app.UIAxes2, "off");

        end

        % Button pushed function: DemodulateButton
        function DemodulateButtonPushed(app, event)
            import bherber.thesis.Tag bherber.thesis.TagType
            res_bits = zeros(1, app.ofsymbolsEditField.Value);
            num_symbs_simd = floor(app.sim.curr_step / app.simstepspersymbolEditField.Value);

            if num_symbs_simd >= app.simconsts.num_symbs
                num_symbs_simd = app.simconsts.num_symbs;
            end
% 
%             symb_times = reshape(app.sim.time, [app.simconsts.symb_sz, num_symbs_simd]);
%             carrier_split = reshape(app.sim.carrier, [app.simconsts.symb_sz, num_symbs_simd]);
%             truncated_simsteps = app.modded_steps(:);
%             modded_symbs = reshape(truncated_simsteps(1:(app.simconsts.symb_sz * num_symbs_simd)), ...
%                 [app.simconsts.symb_sz, num_symbs_simd]);
            modded_symbs = app.aligned.signal;
            carrier_split = app.aligned.carrier;
            symb_times = app.aligned.time;
            if app.sim.tags(app.TagidxEditField.Value).mode == TagType.OOK
                tag = app.sim.tags(app.TagidxEditField.Value);
                parfor idx = 1:num_symbs_simd
                    res_bits(idx) = Tag.ook_demodulate(modded_symbs(:, idx), carrier_split(:, idx), ... 
                         symb_times(:, idx), tag);
                end
            else
                if app.sim.tags(app.TagidxEditField.Value).mode == TagType.FSK_LO
                    f1 = app.simconsts.freq_channels(1).f1;
                    f0 = app.simconsts.freq_channels(1).f0;
                else
                    f1 = app.simconsts.freq_channels(2).f1;
                    f0 = app.simconsts.freq_channels(2).f0;
                end
                parfor idx = 1:num_symbs_simd
                    res_bits(idx) = Tag.fsk_demodulate(modded_symbs(:, idx), carrier_split(:, idx), ... 
                        symb_times(:, idx), f1, f0);
                end
            end

            expected = join(string(app.sim.bits(app.TagidxEditField.Value, :)), "");
            app.UITable_4.Data = [app.UITable_4.Data; [app.TagidxEditField.Value, expected, join(string(res_bits), "")]];
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            app.sim.auto_align();
            delay = floor(app.sim.tag_delays(app.TagidxEditField.Value)) + 1;
            num_symbs_simd = floor(app.sim.curr_step / app.simstepspersymbolEditField.Value);
            totalsignal = app.modded_steps(:);

            if num_symbs_simd >= app.simconsts.num_symbs
                stop = (app.simconsts.num_symbs * app.simconsts.symb_sz) + delay - 1;
                deltime = app.sim.time(delay:stop);
                delcarr = app.sim.carrier(delay:stop);
                delsignal = totalsignal(delay:stop);
                num_symbs_simd = app.simconsts.num_symbs;
            else
                stop = (num_symbs_simd * app.simconsts.symb_sz) - 1;
                deltime = [app.sim.time(delay:stop), zeros(1, delay)];
                delcarr = [app.sim.carrier(delay:stop), zeros(1, delay)];
                delsignal = [totalsignal(delay:stop); zeros(delay, 1)];
            end

            
            app.aligned.time = reshape(deltime, [app.simconsts.symb_sz, num_symbs_simd]);
            app.aligned.carrier = reshape(delcarr, [app.simconsts.symb_sz, num_symbs_simd]);
            app.aligned.signal = reshape(delsignal, [app.simconsts.symb_sz, num_symbs_simd]);

            plot(app.UIAxes2, app.aligned.time(:), app.aligned.signal(:));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CarrierFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CarrierFrequencyEditField.Value = 2400000000;
            app.SimulationParametersLabel = uilabel(app.LeftPanel);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.FsGranularityEditFieldLabel = uilabel(app.LeftPanel);
            app.FsGranularityEditFieldLabel.Text = 'Fs Granularity';
            app.FsGranularityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FsGranularityEditField.Value = 3;
            app.simstepspersymbolEditFieldLabel = uilabel(app.LeftPanel);
            app.simstepspersymbolEditFieldLabel.Text = 'simsteps per symbol';
            app.simstepspersymbolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.simstepspersymbolEditField.Value = 10;
            app.FbBaseEditFieldLabel = uilabel(app.LeftPanel);
            app.FbBaseEditFieldLabel.Text = 'Fb Base';
            app.FbBaseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FbBaseEditField.Value = 75000;
            app.ofsymbolsEditFieldLabel = uilabel(app.LeftPanel);
            app.ofsymbolsEditFieldLabel.Text = '# of symbols';
            app.ofsymbolsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ofsymbolsEditField.Value = 10;
            app.amplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.amplitudeEditField.Value = 0.1;
            app.InterchannelSpacingEditFieldLabel = uilabel(app.LeftPanel);
            app.InterchannelSpacingEditFieldLabel.Text = 'Interchannel Spacing';
            app.InterchannelSpacingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterchannelSpacingEditField.Value = 50000;
            app.FbStepEditFieldLabel = uilabel(app.LeftPanel);
            app.FbStepEditFieldLabel.Text = 'Fb Step';
            app.FbStepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FbStepEditField.Value = 25000;
            app.symbolfreqEditFieldLabel = uilabel(app.LeftPanel);
            app.symbolfreqEditFieldLabel.Text = 'symbol freq';
            app.symbolfreqEditField = uieditfield(app.LeftPanel, 'numeric');
            app.symbolfreqEditField.Value = 100000;
            app.VanAttaelementsEditFieldLabel = uilabel(app.LeftPanel);
            app.VanAttaelementsEditFieldLabel.Text = 'Van Atta elements';
            app.VanAttaelementsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VanAttaelementsEditField.Value = 4;
            app.PatternLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.PatternLengthEditFieldLabel.Text = 'Pattern Length';
            app.PatternLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PatternLengthEditField.Value = 3;
            app.NumChannelsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumChannelsEditFieldLabel.Text = 'Num Channels';
            app.NumChannelsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumChannelsEditField.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TagLocationsTab = uitab(app.TabGroup);
            app.TagLocationsTab.Title = 'Tag Locations';
            app.UIAxes = uiaxes(app.TagLocationsTab);
            title(app.UIAxes, 'Tag Locations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.XEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.UITable_3 = uitable(app.TagLocationsTab);
            app.UITable_3.ColumnName = {'idx'; 'x'; 'y'; 'z'; 'distance'; 'type'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnSortable = true;
            app.TypeDropDown = uidropdown(app.TagLocationsTab);
            app.TypeDropDown.Items = {'FREQ_HOP', 'FSK_LO', 'FSK_HI', 'OOK'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'FSK_LO';
            app.AddButton = uibutton(app.TagLocationsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.NumTagsEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.NumTagsEditField.Value = 2;
            app.RandomFChannelCheckBox = uicheckbox(app.TagLocationsTab);
            app.RandomFChannelCheckBox.Text = 'Random FChannel';
            app.RandomFChannelCheckBox.Value = true;
            app.RandomCreationLabel = uilabel(app.TagLocationsTab);
            app.RandomCreationLabel.Text = 'Random Creation';
            app.ManualCreationLabel = uilabel(app.TagLocationsTab);
            app.ManualCreationLabel.Text = 'Manual Creation';
            app.CreateButton = uibutton(app.TagLocationsTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.deleteidxEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.deleteidxEditField.Value = 1;
            app.DeleteButton = uibutton(app.TagLocationsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MaxZEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.MaxZEditField.Value = 3;
            app.MaxYEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.MaxYEditField.Value = 3;
            app.MaxXEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.MaxXEditField.Value = 3;
            app.XEditFieldLabel = uilabel(app.TagLocationsTab);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.TagLocationsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditFieldLabel = uilabel(app.TagLocationsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.TypeDropDownLabel = uilabel(app.TagLocationsTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.NumTagsEditFieldLabel = uilabel(app.TagLocationsTab);
            app.NumTagsEditFieldLabel.Text = 'Num Tags';
            app.deleteidxEditFieldLabel = uilabel(app.TagLocationsTab);
            app.deleteidxEditFieldLabel.Text = 'delete idx';
            app.MaxZEditFieldLabel = uilabel(app.TagLocationsTab);
            app.MaxZEditFieldLabel.Text = 'Max Z';
            app.MaxYEditFieldLabel = uilabel(app.TagLocationsTab);
            app.MaxYEditFieldLabel.Text = 'Max Y';
            app.MaxXEditFieldLabel = uilabel(app.TagLocationsTab);
            app.MaxXEditFieldLabel.Text = 'Max X';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes2 = uiaxes(app.SimulationTab);
            title(app.UIAxes2, 'Simulator Output')
            xlabel(app.UIAxes2, 'Time (t)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SimulateButton = uibutton(app.SimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SimulationLabel = uilabel(app.SimulationTab);
            app.SimulationLabel.Text = 'Simulation';
            app.DataSigButton = uibutton(app.SimulationTab, 'push');
            app.DataSigButton.ButtonPushedFcn = createCallbackFcn(app, @DataSigButtonPushed, true);
            app.DataSigButton.Text = 'Data Sig';
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AlignButton = uibutton(app.SimulationTab, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.UITable_4 = uitable(app.SimulationTab);
            app.UITable_4.ColumnName = {'tag_idx'; 'known_bitstream'; 'received_bitstream'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnSortable = true;
            app.DemodulateButton = uibutton(app.SimulationTab, 'push');
            app.DemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulateButtonPushed, true);
            app.DemodulateButton.Text = 'Demodulate';
            app.NumSimStepsEditFieldLabel = uilabel(app.SimulationTab);
            app.NumSimStepsEditFieldLabel.Text = 'Num SimSteps';
            app.NumSimStepsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.NumSimStepsEditField.Value = 110;
            app.TagidxEditFieldLabel = uilabel(app.SimulationTab);
            app.TagidxEditFieldLabel.Text = 'Tag idx';
            app.TagidxEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TagidxEditField.Value = 1;
            app.SNRdbEditFieldLabel = uilabel(app.SimulationTab);
            app.SNRdbEditFieldLabel.Text = 'SNR (db)';
            app.SNRdbEditField = uieditfield(app.SimulationTab, 'numeric');
            app.SNRdbEditField.Value = 20;
            app.FrequencyHoppingTab = uitab(app.TabGroup);
            app.FrequencyHoppingTab.Title = 'Frequency Hopping';
            app.UIAxes3 = uiaxes(app.FrequencyHoppingTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end